classdef final_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DropDownCondense     matlab.ui.control.DropDown
        Label_13             matlab.ui.control.Label
        DropDownMorphologic  matlab.ui.control.DropDown
        Label_12             matlab.ui.control.Label
        DropDownThreshold    matlab.ui.control.DropDown
        Label_11             matlab.ui.control.Label
        DropDownEdge         matlab.ui.control.DropDown
        Label_10             matlab.ui.control.Label
        DropDownColor        matlab.ui.control.DropDown
        Label_9              matlab.ui.control.Label
        DropDownButter       matlab.ui.control.DropDown
        Label_8              matlab.ui.control.Label
        DropDownJunMid       matlab.ui.control.DropDown
        Label_7              matlab.ui.control.Label
        DropDownHist         matlab.ui.control.DropDown
        Label_6              matlab.ui.control.Label
        DropDownKongyu       matlab.ui.control.DropDown
        Label_5              matlab.ui.control.Label
        EditFieldDouble_2    matlab.ui.control.NumericEditField
        EditFieldDouble_1    matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        DropDownChange       matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        EditFieldSingle      matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        DropDownCalculate    matlab.ui.control.DropDown
        Label                matlab.ui.control.Label
        ButtonOpen_2         matlab.ui.control.Button
        ButtonClose          matlab.ui.control.Button
        ButtonOpen           matlab.ui.control.Button
        UIAxesO2             matlab.ui.control.UIAxes
        UIAxesFina           matlab.ui.control.UIAxes
        UIAxesO1             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property % Description
        file=0;
        file1=0;
        file2=0;
        im=0;
        map=0;
        im1=0;
        im2=0;
        new_im=0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonOpen
        function ButtonOpenPushed(app, event)
            [filename,pathname]=uigetfile("*.*","载入图像");
            if isequal(filename,0)||isequal(pathname,0)
                errordlg("未选中文件");
            else
                app.file=strcat(pathname,filename);
                [app.im,app.map]=imread(app.file);
                imshow(app.im,app.map,'Parent',app.UIAxesO1);
            end
        end

        % Button pushed function: ButtonClose
        function ButtonClosePushed(app, event)
            choice=questdlg("是否退出？","退出",'yes','no','no');
            switch choice
                case 'yes'
                    delete(app.UIFigure);
                    clear;
                    return;
                case 'no'
                    return;
            end
        end

        % Button pushed function: ButtonOpen_2
        function ButtonOpen_2Pushed(app, event)
            choice=questdlg("是否保存变化后的图像？","保存","yes","no","");
            switch choice
                case 'yes'
                    pathname=uigetdir("*","保存图像");
                    filename=char(inputdlg("请输入要保存的文件名",""));
                    Newfile=strcat(pathname,"\",filename);
                    imwrite(app.new_im,Newfile);
                    helpdlg("文件已成功保存"+Newfile,"提示");
                    return;
                case'no'
                    return;
            end
        end

        % Value changed function: DropDownCalculate
        function DropDownCalculateValueChanged(app, event)
            value = app.DropDownCalculate.Value;
            switch value
                case '无'
                    helpdlg("不进行任何运算！","提示");
                    return;
                case '加法'
                    %选择图像1
                    [filename,pathname]=uigetfile("*.*","载入图像1");
                    if isequal(filename,0)||isequal(pathname,0)
                        errordlg("未选中文件");return;
                    end
                    app.file1=strcat(pathname,filename);
                    [app.im1,map1]=imread(app.file1);
                    imshow(app.im1,map1,'Parent',app.UIAxesO1);
                    [M1,N1,K1]=size(app.im1);
                    %选择图像2
                    [filename,pathname]=uigetfile("*.*","载入图像2");
                    if isequal(filename,0)||isequal(pathname,0)
                        errordlg("未选中文件");return;
                    end
                    app.file2=strcat(pathname,filename);
                    [app.im2,map2]=imread(app.file2);
                    imshow(app.im2,map2,'Parent',app.UIAxesO2);
                    [M2,N2,K2]=size(app.im2);
                    %获取最小尺寸
                    m=min(M1,M2);
                    n=min(N1,N2);
                    %RGB分层
                    R1=app.im1(:,:,1);
                    R2=app.im2(:,:,1);
                    %针对图片维度进行区分
                    if K1==3
                        G1=app.im1(:,:,2);
                        B1=app.im1(:,:,3);
                    else
                        G1=R1;
                        B1=R1;
                    end
                    if K2==3
                        G2=app.im2(:,:,2);
                        B2=app.im2(:,:,3);
                    else
                        G2=R2;
                        B2=R2;
                    end
                    X1=0.5*R1(1:m,1:n)+0.5*R2(1:m,1:n);
                    X2=0.5*G1(1:m,1:n)+0.5*G2(1:m,1:n);
                    X3=0.5*B1(1:m,1:n)+0.5*B2(1:m,1:n);
                    X=cat(3,X1,X2,X3);
                    app.new_im=X;
                    imshow(app.new_im,'Parent',app.UIAxesFina);
                    return;
                case '减法'
                    %选择图像1
                    [filename,pathname]=uigetfile("*.*","载入图像1");
                    if isequal(filename,0)||isequal(pathname,0)
                        errordlg("未选中文件");return;
                    end
                    app.file1=strcat(pathname,filename);
                    [app.im1,map1]=imread(app.file1);
                    imshow(app.im1,map1,'Parent',app.UIAxesO1);
                    [M1,N1,K1]=size(app.im1);
                    %选择图像2
                    [filename,pathname]=uigetfile("*.*","载入图像2");
                    if isequal(filename,0)||isequal(pathname,0)
                        errordlg("未选中文件");return;
                    end
                    app.file2=strcat(pathname,filename);
                    [app.im2,map2]=imread(app.file2);
                    imshow(app.im2,map2,'Parent',app.UIAxesO2);
                    [M2,N2,K2]=size(app.im2);
                    %进行减法
                    %获取最小尺寸
                    m=min(M1,M2);
                    n=min(N1,N2);
                    I1=double(app.im1);
                    I2=double(app.im2);
                    for i=1:m
                        for j=1:n
                            g(i,j)=I1(i,j)-I2(i,j);
                            g(i,j)=(g(i,j)+255)/2;
                        end
                    end
                    app.new_im=uint8(g);
                    imshow(app.new_im,'Parent',app.UIAxesFina);
                    return;
                case '乘法'
                    %先输入乘法倍数
                    msgbox("请提前在单一数值输入区内输入合适的乘法倍数",'提示','help');
                    t=app.EditFieldSingle.Value;
                    %选择图像1
                    [filename,pathname]=uigetfile("*.*","载入图像1");
                    if isequal(filename,0)||isequal(pathname,0)
                        errordlg("未选中文件");return;
                    end
                    app.file1=strcat(pathname,filename);
                    [app.im1,map1]=imread(app.file1);
                    imshow(app.im1,map1,'Parent',app.UIAxesO1);
                    app.new_im=app.im1.*t;
                    imshow(app.new_im,'Parent',app.UIAxesFina);
                    return;
            end
        end

        % Value changed function: DropDownChange
        function DropDownChangeValueChanged(app, event)
            value = app.DropDownChange.Value;
            if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
            else
                [M,N,K]=size(app.im);
                switch value
                    case '无'
                        helpdlg("不进行任何运算！","提示");
                        return;
                    case '平移'
                        msgbox("需提前在双数值区域输入x,y坐标平移的像素值",'提示','help');
                        a=app.EditFieldDouble_1.Value;b=app.EditFieldDouble_2.Value;
                        if K==1%说明读入的是一个二维数组
                            g=zeros(M,N,class(app.im));
                            for i=1:M
                                for j=1:N
                                    if((i-a>0)&&(i-a<M)&&(j-b>0)&&(j-b)<N)
                                        g(i,j)=I(i-a,j-b);
                                    else
                                        g(i,j)=0;
                                    end
                                end
                            end
                            app.new_im=g;
                            imshow(app.new_im,app.map,'Parent',app.UIAxesFina);
                        else%说明读入的是一个三维数组
                            R=app.im(:,:,1);
                            G=app.im(:,:,2);
                            B=app.im(:,:,3);
                            h1=zeros(M,N,class(R));
                            h2=zeros(M,N,class(R));
                            h3=zeros(M,N,class(R));
                            for i=1:M
                                for j=1:N
                                    if((i-a>0)&&(i-a<M)&&(j-b>0)&&(j-b)<N)
                                        h1(i,j)=R(i-a,j-b);
                                    else
                                        h1(i,j)=0;
                                    end
                                end
                            end
                            for i=1:M
                                for j=1:N
                                    if((i-a>0)&&(i-a<M)&&(j-b>0)&&(j-b)<N)
                                        h2(i,j)=G(i-a,j-b);
                                    else
                                        h2(i,j)=0;
                                    end
                                end
                            end
                            for i=1:M
                                for j=1:N
                                    if((i-a>0)&&(i-a<M)&&(j-b>0)&&(j-b)<N)
                                        h3(i,j)=B(i-a,j-b);
                                    else
                                        h3(i,j)=0;
                                    end
                                end
                            end
                            app.new_im=cat(3,h1,h2,h3);
                            imshow(app.new_im,app.map,'Parent',app.UIAxesFina);
                        end
                        return;
                    case '旋转'
                        msgbox("需提前在单一数值输入区输入数值",'提示','help');
                        r=app.EditFieldSingle.Value;
                        app.new_im=imrotate(app.im,r,"bilinear");
                        imshow(app.new_im,app.map,'Parent',app.UIAxesFina);
                        return;
                    case '镜像'
                        if K==1%说明读入的是一个二维数组
                            g=zeros(M,N,class(app.im));
                            for i=1:M
                                for j=1:N
                                    g(M-i+1,j)=app.im(i,j);
                                end
                            end
                            app.new_im=g;
                            imshow(app.new_im,app.map,'Parent',app.UIAxesFina);
                        else
                            R=app.im(:,:,1);
                            G=app.im(:,:,2);
                            B=app.im(:,:,3);
                            h1=zeros(M,N,class(R));
                            h2=zeros(M,N,class(R));
                            h3=zeros(M,N,class(R));
                            for i=1:M
                                for j=1:N
                                    h1(M-i+1,j)=R(i,j);
                                end
                            end
                            for i=1:M
                                for j=1:N
                                    h2(M-i+1,j)=G(i,j);
                                end
                            end
                            for i=1:M
                                for j=1:N
                                    h3(M-i+1,j)=B(i,j);
                                end
                            end
                            app.new_im=cat(3,h1,h2,h3);
                            imshow(app.new_im,app.map,'Parent',app.UIAxesFina);
                        end
                        return;
                    case '转置'
                        g=zeros(N,M,K);
                        I=im2double(app.im);
                        for i=1:M
                            for j=1:N
                                for k=1:K
                                    g(j,i,k)=I(i,j,k);
                                end
                            end
                        end
                        app.new_im=g;
                        imshow(app.new_im,'Parent',app.UIAxesFina);
                        return;
                    case '缩放'
                        msgbox("需提前在单一数值输入区输入数值",'提示','help');
                        r=app.EditFieldSingle.Value;
                        app.new_im=imresize(app.im,r,"bilinear");
                        imshow(app.new_im,app.map,'Parent',app.UIAxesFina);
                        return;
                    case '百叶窗效果'
                        A=app.im;
                        A=double(A)/255;
                        size_info=size(A);
                        height=size_info(1);
                        width=size_info(2);
                        Map=zeros(height, width);
                        for row_i=1:height
                            Map(row_i, :)=1-row_i/height;
                        end
                        H_shade=10;
                        Interval=5;
                        Num=height/(H_shade+Interval);
                        Num=floor(Num);
                        mask=ones(height, width);
                        for j=1:Num+1
                            if(j<=Num)
                                begin_1=1+(j-1)*(H_shade+Interval);
                                mask(begin_1:begin_1+H_shade-1,:)=0;
                            else
                                begin_1=1+Num*(H_shade+Interval);
                                mask(begin_1:height, :)=0;
                            end

                        end
                        for kk=1:3
                            A(:,:,kk)=A(:,:,kk).*(1-mask)+Map.*mask;
                        end
                        app.new_im=A;
                        imshow(app.new_im,'Parent',app.UIAxesFina);
                        return;
                end
            end
        end

        % Value changed function: DropDownKongyu
        function DropDownKongyuValueChanged(app, event)
            value = app.DropDownKongyu.Value;
            if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
            else
                [M,N,K]=size(app.im);
                switch value
                    case '反相'
                        if K==3%说明读入三维矩阵
                            R=app.im(:,:,1);
                            G=app.im(:,:,2);
                            B=app.im(:,:,3);
                            Ir1=255-R;
                            Ir2=255-G;
                            Ir3=255-B;
                            app.new_im=cat(3,Ir1,Ir2,Ir3);
                        else%说明读入二维矩阵
                            app.new_im=255-app.im;
                        end
                        imshow(app.new_im,'Parent',app.UIAxesFina);
                    case '亮度'
                        msgbox('需提前在双数值输入区内输入数值,前一个为系数，后一个为位移量','提示','help');
                        a=app.EditFieldDouble_1.Value;b=app.EditFieldDouble_2.Value;
                        app.new_im=a.*app.im+b;
                        imshow(app.new_im,'Parent',app.UIAxesFina);
                    case '对比度'
                        I=app.im;
                        R=I(:,:,1);
                        G=I(:,:,2);
                        B=I(:,:,3);
                        [M,N]=size(R);
                        R=double(R);
                        G=double(G);
                        B=double(B);
                        r=zeros(M,N);
                        g=zeros(M,N);
                        b=zeros(M,N);
                        for i=1:M
                            for j=1:N
                                if R(i,j)<100
                                    r(i,j)=R(i,j)*0.7;
                                elseif R(i,j)<160
                                    r(i,j)=R(i,j)*1;
                                else
                                    r(i,j)=R(i,j)*1.5;
                                end
                            end
                        end
                        for i=1:M
                            for j=1:N
                                if G(i,j)<100
                                    g(i,j)=G(i,j)*0.7;
                                elseif G(i,j)<160
                                    g(i,j)=G(i,j)*1;
                                else
                                    g(i,j)=G(i,j)*1.5;
                                end
                            end
                        end
                        for i=1:M
                            for j=1:N
                                if B(i,j)<100
                                    b(i,j)=B(i,j)*0.7;
                                elseif R(i,j)<160
                                    b(i,j)=B(i,j)*1;
                                else
                                    b(i,j)=B(i,j)*1.5;
                                end
                            end
                        end
                        Y=cat(3,r,g,b);
                        app.new_im=uint8(Y);
                        imshow(app.new_im,'Parent',app.UIAxesFina);
                end
            end
        end

        % Value changed function: DropDownHist
        function DropDownHistValueChanged(app, event)
            value = app.DropDownHist.Value;
             if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
             else
                 figure,imhist(app.im),title('原图的直方图');
                 switch value
                     case '直方图均衡化'
                         app.new_im=histeq(app.im);
                         imshow(app.new_im,'Parent',app.UIAxesFina);
                     case '直方图规定化'
                         [filename,pathname]=uigetfile("*.*","选择图片以产生规定化模板");
                         if isequal(filename,0)||isequal(pathname,0)
                             errordlg("未选中文件");return;
                         else
                             app.file2=strcat(pathname,filename);
                             [app.im2,map2]=imread(app.file2);
                             imshow(app.im2,map2,'Parent',app.UIAxesO2);
                             figure,imhist(app.im2),title('模板图的直方图')
                             model=imhist(app.im2);
                             app.new_im=histeq(app.im,model);
                             imshow(app.new_im,'Parent',app.UIAxesFina);
                         end
                     case '灰度拉伸'
                         msgbox('请输入灰度拉伸的区间和拉伸后的区间','提示','help');
                         a=input('input a number');
                         b=input('input a number');
                         a1=input('input a number');
                         b1=input('input a number');
                         app.new_im=imadjust(app.im,[a,b],[a1,b1]);
                         imshow(app.new_im,'Parent',app.UIAxesFina);
                 end
                 figure,imhist(app.new_im),title('结果图的直方图');
             end
        end

        % Value changed function: DropDownJunMid
        function DropDownJunMidValueChanged(app, event)
            value = app.DropDownJunMid.Value;
            if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
            else
                [M,N,K]=size(app.im);
                switch value
                    case '均值滤波'
                        h1=ones(9,9)/81;
                        app.new_im=imfilter(app.im,h1);
                    case '中值滤波'
                        if K==3
                            R=app.im(:,:,1);
                            G=app.im(:,:,2);
                            B=app.im(:,:,3);
                            R1=medfilt2(R,[3,3]);
                            G1=medfilt2(G,[3,3]);
                            B1=medfilt2(B,[3,3]);
                            app.new_im=cat(3,R1,G1,B1);
                        else
                            app.new_im=medfilt2(app.im,[3,3]);
                        end
                end
                imshow(app.new_im,'Parent',app.UIAxesFina);
            end
        end

        % Value changed function: DropDownButter
        function DropDownButterValueChanged(app, event)
            value = app.DropDownButter.Value;
             if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
             else
                 [M,N,K]=size(app.im);
                 switch value
                     case '理想低通滤波'
                         if K==3
                             f=im2double(app.im);
                             R=f(:,:,1);
                             G=f(:,:,2);
                             B=f(:,:,3);  
                             R1=fftshift(fft2(R));
                             G1=fftshift(fft2(G));
                             B1=fftshift(fft2(B));
                             [M,N]=size(R);
                             g1=[M,N];
                             g2=[M,N];
                             g3=[M,N];
                             D0=30;
                             u0=floor(M/2);v0=floor(N/2);
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D>D0
                                         g1(u,v)=0;
                                     else
                                         g1(u,v)=R1(u,v);
                                     end
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D>D0
                                         g2(u,v)=0;
                                     else
                                         g2(u,v)=G1(u,v);
                                     end
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D>D0
                                         g3(u,v)=0;
                                     else
                                         g3(u,v)=B1(u,v);
                                     end
                                 end
                             end
                             g1=ifft2(ifftshift(g1));
                             g1=im2uint8(real(g1));
                             g2=ifft2(ifftshift(g2));
                             g2=im2uint8(real(g2));
                             g3=ifft2(ifftshift(g3));
                             g3=im2uint8(real(g3));
                             app.new_im=cat(3,g1,g2,g3);
                         else
                             f=im2double(app.im);
                             F=fftshift(fft2(f));
                             [M,N]=size(f);
                             g=[M,N];
                             D0=30;
                             u0=floor(M/2);v0=floor(N/2);
                              for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D>D0
                                         g(u,v)=0;
                                     else
                                         g(u,v)=F(u,v);
                                     end
                                 end
                              end
                              g=ifft2(ifftshift(g));
                              g=im2uint8(real(g));
                              app.new_im=g;
                         end
                     case '理想高通滤波'
                         if K==3
                             f=im2double(app.im);
                             R=f(:,:,1);
                             G=f(:,:,2);
                             B=f(:,:,3);
                             R1=fftshift(fft2(R));
                             G1=fftshift(fft2(G));
                             B1=fftshift(fft2(B));
                             [M,N]=size(R);
                             g1=[M,N];
                             g2=[M,N];
                             g3=[M,N];
                             D0=30;
                             u0=floor(M/2);v0=floor(N/2);
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D<=D0
                                         g1(u,v)=0;
                                     else
                                         g1(u,v)=R1(u,v);
                                     end
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D<=D0
                                         g2(u,v)=0;
                                     else
                                         g2(u,v)=G1(u,v);
                                     end
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D<=D0
                                         g3(u,v)=0;
                                     else
                                         g3(u,v)=B1(u,v);
                                     end
                                 end
                             end
                             g1=ifft2(ifftshift(g1));
                             g1=im2uint8(real(g1));
                             g2=ifft2(ifftshift(g2));
                             g2=im2uint8(real(g2));
                             g3=ifft2(ifftshift(g3));
                             g3=im2uint8(real(g3));
                             app.new_im=cat(3,g1,g2,g3);
                         else
                             f=im2double(app.im);
                             F=fftshift(fft2(f));
                             [M,N]=size(f);
                             g=[M,N];
                             D0=30;
                             u0=floor(M/2);v0=floor(N/2);
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     if D<=D0
                                         g(u,v)=0;
                                     else
                                         g(u,v)=F(u,v);
                                     end
                                 end
                             end
                             g=ifft2(ifftshift(g));
                             g=im2uint8(real(g));
                             app.new_im=g;
                         end
                     case 'Butterworth高通滤波'
                          if K==3
                             f=im2double(app.im);
                             R=f(:,:,1);
                             G=f(:,:,2);
                             B=f(:,:,3);
                             R1=fftshift(fft2(R));
                             G1=fftshift(fft2(G));
                             B1=fftshift(fft2(B));
                             [M,N]=size(R);
                             g1=[M,N];
                             g2=[M,N];
                             g3=[M,N];
                             n=3;
                             D0=40;
                             u0=floor(M/2);v0=floor(N/2);
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     H=1/(1+(D0/D)^(2*n));
                                     g1(u,v)=H*R1(u,v);
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     H=1/(1+(D0/D)^(2*n));
                                     g2(u,v)=H*G1(u,v);
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     H=1/(1+(D0/D)^(2*n));
                                     g3(u,v)=H*B1(u,v);
                                 end
                             end
                             g1=ifft2(ifftshift(g1));
                             g1=im2uint8(real(g1));
                             g2=ifft2(ifftshift(g2));
                             g2=im2uint8(real(g2));
                             g3=ifft2(ifftshift(g3));
                             g3=im2uint8(real(g3));
                             g=cat(3,g1,g2,g3);
                             app.new_im=g;
                          else
                              f=im2double(app.im);
                              F=fftshift(fft2(f));
                              [M,N]=size(f);
                              g=[M,N];
                              n=3;
                              D0=40;
                              u0=floor(M/2);v0=floor(N/2);
                              for u=1:M
                                  for v=1:N
                                      D=sqrt((u-u0)^2+(v-v0)^2);
                                      H=1/(1+(D0/D)^(2*n));
                                      g(u,v)=H*F(u,v);
                                  end
                              end
                              g=ifft2(ifftshift(g));
                              g=im2uint8(real(g));
                              app.new_im=g;
                          end
                     case 'Butterworth低通滤波'
                          if K==3
                             f=im2double(app.im);
                             R=f(:,:,1);
                             G=f(:,:,2);
                             B=f(:,:,3);
                             R1=fftshift(fft2(R));
                             G1=fftshift(fft2(G));
                             B1=fftshift(fft2(B));
                             [M,N]=size(R);
                             g1=[M,N];
                             g2=[M,N];
                             g3=[M,N];
                             n=3;
                             D0=40;
                             u0=floor(M/2);v0=floor(N/2);
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     H=1/(1+(D/D0)^(2*n));
                                     g1(u,v)=H*R1(u,v);
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     H=1/(1+(D/D0)^(2*n));
                                     g2(u,v)=H*G1(u,v);
                                 end
                             end
                             for u=1:M
                                 for v=1:N
                                     D=sqrt((u-u0)^2+(v-v0)^2);
                                     H=1/(1+(D/D0)^(2*n));
                                     g3(u,v)=H*B1(u,v);
                                 end
                             end
                             g1=ifft2(ifftshift(g1));
                             g1=im2uint8(real(g1));
                             g2=ifft2(ifftshift(g2));
                             g2=im2uint8(real(g2));
                             g3=ifft2(ifftshift(g3));
                             g3=im2uint8(real(g3));
                             g=cat(3,g1,g2,g3);
                             app.new_im=g;
                          else
                              f=im2double(app.im);
                              F=fftshift(fft2(f));
                              [M,N]=size(f);
                              g=[M,N];
                              n=3;
                              D0=40;
                              u0=floor(M/2);v0=floor(N/2);
                              for u=1:M
                                  for v=1:N
                                      D=sqrt((u-u0)^2+(v-v0)^2);
                                      H=1/(1+(D/D0)^(2*n));
                                      g(u,v)=H*F(u,v);
                                  end
                              end
                              g=ifft2(ifftshift(g));
                              g=im2uint8(real(g));
                              app.new_im=g;
                          end
                 end
                imshow(app.new_im,'Parent',app.UIAxesFina); 
             end
        end

        % Value changed function: DropDownColor
        function DropDownColorValueChanged(app, event)
            value = app.DropDownColor.Value;
             if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
             else
                 [M,N,K]=size(app.im);
                 switch value
                     case '伪彩色增强'
                         if K==3
                             errordlg('请读入灰度图');
                             return;
                         else
                             Y=app.im/64;
                             for i=1:M
                                 for j=1:N
                                     switch Y(i,j)%彩虹编码伪彩色增强
                                         case 0
                                             Y(i,j,1:3)=[0 0 255];
                                         case 1
                                             Y(i,j,1:3)=[0 255 0];
                                         case 2
                                             Y(i,j,1:3)=[255 0 0];
                                         case 3
                                             Y(i,j,1:3)=[255 0 255];
                                         otherwise
                                             Y(i,j,1:3)=[255 255 255];
                                     end
                                 end
                             end
                             app.new_im=Y;
                         end
                     case '假彩色增强'
                         if K==3
                             errordlg('请读入灰度图');
                             return;
                         else
                             L=255;
                             f=double(app.im);
                             f1=floor(f/64);
                             R=f1;G=f1;B=f1;
                             for i=1:M
                                 for j=1:N
                                     switch f1(i,j)
                                         case 0
                                             R(i,j)=0;G(i,j)=4*f(i,j);B(i,j)=L;
                                         case 1
                                             R(i,j)=0;G(i,j)=L;B(i,j)=4*f(i,j)+2*L;
                                         case 2
                                             R(i,j)=4*f(i,j)-2*L;G(i,j)=L;B(i,j)=0;
                                         case 3
                                             R(i,j)=L;G(i,j)=-4*f(i,j)+4*L;B(i,j)=0;
                                     end
                                 end
                             end
                             g(:,:,1)=R;g(:,:,2)=G;g(:,:,3)=B;
                             g=uint8(g);
                             app.new_im=g;
                         end
                     case '真彩色增强'
                         if K<3
                             errordlg('请读入真彩色图');
                             return;
                         else
                             I=app.im;
                             R=I(:,:,1);
                             G=I(:,:,2);
                             B=I(:,:,3);
                             R1=histeq(R);
                             G1=histeq(G);
                             B1=histeq(B);
                             I1=cat(3,R1,G1,B1);
                             app.new_im=I1;
                         end
                 end
                 imshow(app.new_im,'Parent',app.UIAxesFina);
             end
        end

        % Value changed function: DropDownThreshold
        function DropDownThresholdValueChanged(app, event)
            value = app.DropDownThreshold.Value;
             if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
             else
                 [M,N,K]=size(app.im);
                 if K==3
                     errordlg('请读入灰度图');
                     return;
                 else
                     switch value
                         case '交互式阈值分割'
                             msgbox('请提前在单一数值输入区内输入阈值分割','提示','help');
                             a=app.EditFieldSingle.Value;
                             if a<0||a>1
                                 errordlg('阈值取值在[0,1]之间');
                                 return;
                             else
                                 app.new_im=im2bw(app.im,a);
                                 imshow(app.new_im,'Parent',app.UIAxesFina);
                             end 
                         case '迭代阈值分割'
                             h=imhist(app.im);
                             t=[];t(1)=(min(app.im(:))+max(app.im(:)))/2;%求初始阈值
                             Max=300;L=256;%迭代次数为300
                             for i=1:Max
                                 s1=0;s2=0;s3=0;s4=0;
                                 %根据阈值t(i)分成两部分再求平均灰度值
                                 for k=1:t(i)
                                     s1=s1+h(k)*k;
                                     s2=s2+h(k);
                                 end
                                 zb=s1/s2;%平均灰度值1
                                 for k=t(i)+1:L
                                     s3=s3+h(k)*k;
                                     s4=s4+h(k);
                                 end
                                 zo=s3/s4;%平均灰度值2
                                 t(i+1)=floor((zb+zo)/2);%计算新的阈值
                                 if(abs(t(i+1)-t(i))<1)%阈值相差极小，退出循环
                                     break;
                                 end
                             end
                             tt=t(i+1);
                             Y=app.im;
                             Y(find(Y<tt))=0;
                             Y(find(Y>=tt))=255;
                             app.new_im=Y;
                             imshow(app.new_im,'Parent',app.UIAxesFina);
                         case 'OTSU阈值分割'
                             T=graythresh(app.im);
                             app.new_im=im2bw(app.im,T);
                             imshow(app.new_im,'Parent',app.UIAxesFina);
                         case '局部阈值分割'
                             msgbox('请提前在单一数值输入区内输入阈值分割','提示','help');
                             a=app.EditFieldSingle.Value;
                             if a<=0
                                 errordlg('单一数值输入区的数值应为正整数');
                                 return;
                             else
                                 T=multithresh(app.im,a);
                                 app.new_im=imquantize(app.im,T);
                                 imshow(app.new_im,[],'Parent',app.UIAxesFina);
                             end
                     end
                 end
             end
        end

        % Value changed function: DropDownMorphologic
        function DropDownMorphologicValueChanged(app, event)
            value = app.DropDownMorphologic.Value;
            if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
            else
                switch value
                    case '膨胀'
                        A=im2bw(app.im);
                        imshow(A,'Parent',app.UIAxesO2);
                        se=strel('disk',2);
                        app.new_im=imdilate(A,se);
                    case '腐蚀'
                        A=im2bw(app.im);
                        imshow(A,'Parent',app.UIAxesO2);
                        se=strel('square',2);
                        app.new_im=imerode(A,se);
                    case '开运算'
                        A=im2bw(app.im);
                        imshow(A,'Parent',app.UIAxesO2);
                        se=strel('square',7);
                        app.new_im=imopen(A,se);
                    case '闭运算'
                        A=im2bw(app.im);
                        imshow(A,'Parent',app.UIAxesO2);
                        se=strel('square',7);
                        app.new_im=imclose(A,se);
                end
                imshow(app.new_im,'Parent',app.UIAxesFina);
            end
        end

        % Value changed function: DropDownEdge
        function DropDownEdgeValueChanged(app, event)
            value = app.DropDownEdge.Value;
            if isequal(app.im,0)||isequal(app.file,0)
                errordlg("请先选择图像");
                return;
            else
                [i,j,k]=size(app.im);
                switch value
                    case '正交梯度算子'
                        if k==3
                            A=app.im;
                            R=A(:,:,1);
                            G=A(:,:,2);
                            B=A(:,:,3);
                            R1=double(R);
                            G1=double(G);
                            B1=double(B);
                            h1=[0 0 0;-1 1 0;0 0 0];h2=[0 -1 0;0 1 0;0 0 0];
                            Gh1=imfilter(R1,h1);
                            Gv1=imfilter(R1,h2);
                            Gh2=imfilter(G1,h1);
                            Gv2=imfilter(G1,h2);
                            Gh3=imfilter(B1,h1);
                            Gv3=imfilter(B1,h2);
                            [X,Y]=size(R);
                            M1=[X,Y];
                            M2=[X,Y];
                            M3=[X,Y];
                            for m=1:X
                                for n=1:Y
                                    M1(m,n)=sqrt(Gh1(m,n)^2+Gv1(m,n)^2);%欧氏距离
                                end
                            end
                            for m=1:X
                                for n=1:Y
                                    M2(m,n)=sqrt(Gh2(m,n)^2+Gv2(m,n)^2);%欧氏距离
                                end
                            end
                            for m=1:X
                                for n=1:Y
                                    M3(m,n)=sqrt(Gh3(m,n)^2+Gv3(m,n)^2);%欧氏距离
                                end
                            end
                            M1=uint8(M1);
                            M2=uint8(M2);
                            M3=uint8(M3);
                            M=cat(3,M1,M2,M3);
                            app.new_im=M;
                        else
                            A=app.im;
                            A1=double(A);
                            h1=[0 0 0;-1 1 0;0 0 0];
                            Gh=imfilter(A1,h1);
                            h2=[0 -1 0;0 1 0;0 0 0];
                            Gv=imfilter(A1,h2);
                            [X,Y]=size(A);
                            G=[X,Y];
                            for m=1:X
                                for n=1:Y
                                    G(m,n)=sqrt(Gh(m,n)^2+Gv(m,n)^2);%欧氏距离
                                end
                            end
                            app.new_im=uint8(G);
                        end
                    case 'sobel方向梯度算子'
                        f=app.im;
                        if k==3
                            f=rgb2gray(f);
                        end
                        f=im2double(f);
                        app.new_im=edge(f,'sobel','both');
                    case 'roberts方向梯度算子'
                        f=app.im;
                        if k==3
                            f=rgb2gray(f);
                        end
                        f=im2double(f);
                        app.new_im=edge(f,'roberts');
                    case 'prewitt方向梯度算子'
                        f=app.im;
                        if k==3
                            f=rgb2gray(f);
                        end
                        f=im2double(f);
                        app.new_im=edge(f,'prewitt','both');
                    case '多方向方向梯度'
                        f=app.im;
                        if k==3
                            f=rgb2gray(f);
                        end
                        f=im2double(f);
                        e1=edge(f,'sobel','both');
                        e2=edge(f,'roberts');
                        e3=edge(f,'prewitt','both');
                        e=e1+e2+e3;
                        [M,N]=size(e);
                        for i=1:M
                            for j=1:N
                                if e(i,j)>0
                                    e(i,j)=1;
                                end
                            end
                        end
                        app.new_im=e;
                end
                imshow(app.new_im,'Parent',app.UIAxesFina);
            end
        end

        % Value changed function: DropDownCondense
        function DropDownCondenseValueChanged(app, event)
            value = app.DropDownCondense.Value;
            switch value
                case '解码'
                    load F:/'Matlab_ R2021b(64bit)'/file.mat;
                    [a,b]=size(numpixel);
                    len=0;
                    for i=1:b
                        len=len+numpixel(i);
                    end
                    I3=zeros(1,len);
                    i=1;
                    for j=1:b
                        for k=1:numpixel(j)
                            I3(i)=pixel(j);
                            i=i+1;
                        end
                    end
                    I2=reshape(I3,M,N);
                    app.new_im=I2;
                    imshow(app.new_im,[],'Parent',app.UIAxesFina);
                case '压缩编码'

                    if isequal(app.im,0)||isequal(app.file,0)
                        errordlg("请先选择图像");
                        return;
                    else
                        [a,b,c]=size(app.im);
                        if c==3
                            errordlg("仅支持二维矩阵图像");
                            return;
                        else
                            I1=app.im;
                            [M,N]=size(I1);
                            I3=I1(:)';
                            I3len=length(I3);
                            j=1;n=1;
                            for z=1:(I3len-1)
                                if I3(z)==I3(z+1)
                                    n=n+1;
                                else
                                    pixel(j)=I3(z);%存放像素颜色信息
                                    numpixel(j)=n;%存放相同颜色像素个数信息
                                    j=j+1;n=1;
                                end
                            end
                            pixel(j)=I3(z+1);
                            numpixel(j)=n;
                            pixel_len=length(pixel);
                            CR=I3len/(pixel_len*2);
                            disp("压缩比:"+CR);
                            disp("原图数据长度："+I3len);
                            disp("压缩后图像数据长度："+pixel_len);
                            save file.mat numpixel pixel M N;
                        end
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesO1 = uiaxes(app.UIFigure);
            title(app.UIAxesO1, '原图1')
            zlabel(app.UIAxesO1, 'Z')
            app.UIAxesO1.XTick = [];
            app.UIAxesO1.YTick = [];
            app.UIAxesFina = uiaxes(app.UIFigure);
            title(app.UIAxesFina, '结果图')
            zlabel(app.UIAxesFina, 'Z')
            app.UIAxesFina.XTick = [];
            app.UIAxesFina.YTick = [];
            app.UIAxesO2 = uiaxes(app.UIFigure);
            title(app.UIAxesO2, '原图2')
            zlabel(app.UIAxesO2, 'Z')
            app.UIAxesO2.XTick = [];
            app.UIAxesO2.YTick = [];
            app.ButtonOpen = uibutton(app.UIFigure, 'push');
            app.ButtonOpen.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenPushed, true);
            app.ButtonOpen.Text = '选择图片';
            app.ButtonClose = uibutton(app.UIFigure, 'push');
            app.ButtonClose.ButtonPushedFcn = createCallbackFcn(app, @ButtonClosePushed, true);
            app.ButtonClose.Tooltip = {'单击以退出应用程序'};
            app.ButtonClose.Text = 'X';
            app.ButtonOpen_2 = uibutton(app.UIFigure, 'push');
            app.ButtonOpen_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpen_2Pushed, true);
            app.ButtonOpen_2.Text = '保存图片';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图像的算术运算';
            app.DropDownCalculate = uidropdown(app.UIFigure);
            app.DropDownCalculate.Items = {'无', '加法', '减法', '乘法'};
            app.DropDownCalculate.ValueChangedFcn = createCallbackFcn(app, @DropDownCalculateValueChanged, true);
            app.DropDownCalculate.Tooltip = {'若要进行乘法需要先更新数值输入区'};
            app.DropDownCalculate.Value = '无';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '单一数值输入区：';
            app.EditFieldSingle = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '图像的几何变换';
            app.DropDownChange = uidropdown(app.UIFigure);
            app.DropDownChange.Items = {'无', '百叶窗效果', '平移', '旋转', '缩放', '镜像', '转置'};
            app.DropDownChange.ValueChangedFcn = createCallbackFcn(app, @DropDownChangeValueChanged, true);
            app.DropDownChange.Tooltip = {''};
            app.DropDownChange.Value = '无';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '双数值输入区：';
            app.EditFieldDouble_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldDouble_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '图像增强之空域';
            app.DropDownKongyu = uidropdown(app.UIFigure);
            app.DropDownKongyu.Items = {'无', '亮度', '对比度', '反相'};
            app.DropDownKongyu.ValueChangedFcn = createCallbackFcn(app, @DropDownKongyuValueChanged, true);
            app.DropDownKongyu.Tooltip = {''};
            app.DropDownKongyu.Value = '无';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '图像的灰度拉伸';
            app.DropDownHist = uidropdown(app.UIFigure);
            app.DropDownHist.Items = {'无', '灰度拉伸', '直方图均衡化', '直方图规定化'};
            app.DropDownHist.ValueChangedFcn = createCallbackFcn(app, @DropDownHistValueChanged, true);
            app.DropDownHist.Tooltip = {''};
            app.DropDownHist.Value = '无';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '图像空间域平滑';
            app.DropDownJunMid = uidropdown(app.UIFigure);
            app.DropDownJunMid.Items = {'无', '均值滤波', '中值滤波'};
            app.DropDownJunMid.ValueChangedFcn = createCallbackFcn(app, @DropDownJunMidValueChanged, true);
            app.DropDownJunMid.Tooltip = {''};
            app.DropDownJunMid.Value = '无';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '图像的频域增强';
            app.DropDownButter = uidropdown(app.UIFigure);
            app.DropDownButter.Items = {'无', '理想低通滤波', '理想高通滤波', 'Butterworth低通滤波', 'Butterworth高通滤波'};
            app.DropDownButter.ValueChangedFcn = createCallbackFcn(app, @DropDownButterValueChanged, true);
            app.DropDownButter.Tooltip = {''};
            app.DropDownButter.Value = '无';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '图像的色彩增强';
            app.DropDownColor = uidropdown(app.UIFigure);
            app.DropDownColor.Items = {'无', '伪彩色增强', '假彩色增强', '真彩色增强'};
            app.DropDownColor.ValueChangedFcn = createCallbackFcn(app, @DropDownColorValueChanged, true);
            app.DropDownColor.Tooltip = {''};
            app.DropDownColor.Value = '无';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '图像的边缘检测';
            app.DropDownEdge = uidropdown(app.UIFigure);
            app.DropDownEdge.Items = {'无', '多方向方向梯度', 'roberts方向梯度算子', 'prewitt方向梯度算子', '正交梯度算子', 'sobel方向梯度算子'};
            app.DropDownEdge.ValueChangedFcn = createCallbackFcn(app, @DropDownEdgeValueChanged, true);
            app.DropDownEdge.Tooltip = {''};
            app.DropDownEdge.Value = '无';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '图像的阈值分割';
            app.DropDownThreshold = uidropdown(app.UIFigure);
            app.DropDownThreshold.Items = {'无', '局部阈值分割', 'OTSU阈值分割', '交互式阈值分割', '迭代阈值分割'};
            app.DropDownThreshold.ValueChangedFcn = createCallbackFcn(app, @DropDownThresholdValueChanged, true);
            app.DropDownThreshold.Tooltip = {''};
            app.DropDownThreshold.Value = '无';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '二值图像形态学处理';
            app.DropDownMorphologic = uidropdown(app.UIFigure);
            app.DropDownMorphologic.Items = {'无', '膨胀', '腐蚀', '开运算', '闭运算'};
            app.DropDownMorphologic.ValueChangedFcn = createCallbackFcn(app, @DropDownMorphologicValueChanged, true);
            app.DropDownMorphologic.Tooltip = {''};
            app.DropDownMorphologic.Value = '无';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '行程编码压缩';
            app.DropDownCondense = uidropdown(app.UIFigure);
            app.DropDownCondense.Items = {'无', '压缩编码', '解码'};
            app.DropDownCondense.ValueChangedFcn = createCallbackFcn(app, @DropDownCondenseValueChanged, true);
            app.DropDownCondense.Tooltip = {''};
            app.DropDownCondense.Value = '无';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end